classdef BLapp2020 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        TpSliderLabel             matlab.ui.control.Label
        TpSlider                  matlab.ui.control.Slider
        TfSliderLabel             matlab.ui.control.Label
        TfSlider                  matlab.ui.control.Slider
        TvSliderLabel             matlab.ui.control.Label
        TvSlider                  matlab.ui.control.Slider
        CirculatoryCheckBox       matlab.ui.control.CheckBox
        ImpulsiveCheckBox         matlab.ui.control.CheckBox
        PotentialCheckBox         matlab.ui.control.CheckBox
        AccVortexCheckBox         matlab.ui.control.CheckBox
        TotalCheckBox             matlab.ui.control.CheckBox
        ExperimentalCheckBox      matlab.ui.control.CheckBox
        ModKirchoffCheckBox       matlab.ui.control.CheckBox
        LaggedCheckBox            matlab.ui.control.CheckBox
        DatasetDropDownLabel      matlab.ui.control.Label
        DatasetDropDown           matlab.ui.control.DropDown
        ModelDropDownLabel        matlab.ui.control.Label
        ModelDropDown             matlab.ui.control.DropDown
        FilterexpdataSwitchLabel  matlab.ui.control.Label
        FilterSwitch              matlab.ui.control.Switch
        TfField                   matlab.ui.control.NumericEditField
        TvField                   matlab.ui.control.NumericEditField
        TvlSliderLabel            matlab.ui.control.Label
        TvlSlider                 matlab.ui.control.Slider
        TpField                   matlab.ui.control.NumericEditField
        TvlField                  matlab.ui.control.NumericEditField
        cDropDownLabel            matlab.ui.control.Label
        CaseDropDown              matlab.ui.control.DropDown
        StaticCheckBox            matlab.ui.control.CheckBox
        RightPanel                matlab.ui.container.Panel
        TabGroup                  matlab.ui.container.TabGroup
        loadsinangleTab           matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        SaveFigureButton          matlab.ui.control.Button
        loadsintimeTab            matlab.ui.container.Tab
        UIAxesT                   matlab.ui.control.UIAxes
        SaveFigureButton_T        matlab.ui.control.Button
        seppointinalphaTab        matlab.ui.container.Tab
        UIAxes2                   matlab.ui.control.UIAxes
        SaveFigureButton_2        matlab.ui.control.Button
        seppointintimeTab         matlab.ui.container.Tab
        UIAxes2T                  matlab.ui.control.UIAxes
        SaveFigureButton_2T       matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % airfoil motion
        motion
        airfoil
        Tp = 1.7;
        Tf = 3;
        Tv = 6;
        Tvl = 6;
        params
        cmap
    end
    
    methods (Access = private)
        function updatePlots(app)
            
            switch app.ModelDropDown.Value
                case 'Beddoes-Leishman'
                    app.motion.BeddoesLeishman(app.airfoil,app.Tp,app.Tf,app.Tv,app.Tvl,'experimental');
                    app.params = sprintf('r=%0.3f,T_p=%0.1f, T_f=%0.1f, T_v=%0.1f, T_{vl}=%0.1f',app.motion.r,app.Tp,app.Tf,app.Tv,app.Tvl);
                case 'BL-Sheng'
                    app.motion.BLSheng(app.airfoil,app.Tf,app.Tv,app.Tvl,'experimental');
                    load('/Users/lucas/Documents/EPFL/PDM/linfit_flatplate.mat','Talpha');
                    app.params = sprintf('r=%0.3f,T_\\alpha=%0.2f, T_f=%0.1f, T_v=%0.1f, T_{vl}=%0.1f',app.motion.r,Talpha,app.Tf,app.Tv,app.Tvl);
                case 'BL-Sheng with expfit'
                    app.motion.BLexpfit(app.airfoil,app.Tf,app.Tv,app.Tvl,'experimental');
                    [~,Talpha] = Expfit(app.airfoil,app.motion);
                    app.params = sprintf('r=%0.3f,T_\\alpha=%0.2f, T_f=%0.1f, T_v=%0.1f, T_{vl}=%0.1f',app.motion.r,Talpha,app.Tf,app.Tv,app.Tvl);
                otherwise
                    error('The selected model is not a valid option.')
            end
            
            app.plotCN()
            app.plotf()
        end
        
        function plotCN(app)
            hold(app.UIAxes,'off')
            hold(app.UIAxesT,'off')
            
            if strcmp(app.DatasetDropDown.Value,'simcos 2008')
                t_end = 1/app.motion.freq;
            else
                t_end = Inf;
            end
            
            
            if app.ExperimentalCheckBox.Value
                plot(app.UIAxes,app.motion.alpha,app.motion.CN,"Color",app.cmap(1,:),"LineWidth",2,'DisplayName','exp')
                hold(app.UIAxes,'on')
                plot(app.UIAxesT,app.motion.S(1:find(app.motion.t<t_end,1,'last')),app.motion.CN(app.motion.t<t_end),"Color",app.cmap(1,:),"LineWidth",2,'DisplayName','exp')
                hold(app.UIAxesT,'on')
            end
            
            if app.StaticCheckBox.Value
                plot(app.UIAxes,app.motion.alpha(1:length(app.motion.CNsteady)),app.motion.CNsteady,"Color",app.cmap(5,:),"LineWidth",1,'DisplayName','C_N^C')
                hold(app.UIAxes,'on')
                plot(app.UIAxesT,app.motion.S(1:min([length(app.motion.CNsteady),find(app.motion.t<t_end,1,'last')])),app.motion.CNsteady(1:min([length(app.motion.CNsteady),find(app.motion.t<t_end,1,'last')])),"Color",app.cmap(5,:),"LineWidth",1,'DisplayName','C_N^{static}')
                hold(app.UIAxesT,'on')
                
            end 
            
            
            if app.CirculatoryCheckBox.Value
                plot(app.UIAxes,app.motion.alpha(1:length(app.motion.CNC)),app.motion.CNC,"Color",app.cmap(2,:),"LineWidth",1,'DisplayName','C_N^C')
                hold(app.UIAxes,'on')
                plot(app.UIAxesT,app.motion.S(1:min([length(app.motion.CNC),find(app.motion.t<t_end,1,'last')])),app.motion.CNC(1:min([length(app.motion.CNC),find(app.motion.t<t_end,1,'last')])),"Color",app.cmap(2,:),"LineWidth",1,'DisplayName','C_N^C')
                hold(app.UIAxesT,'on')
                
            end
            
            if app.ImpulsiveCheckBox.Value
                plot(app.UIAxes,app.motion.alpha(1:length(app.motion.CNI)),app.motion.CNI,"Color",app.cmap(3,:),"LineWidth",1,'DisplayName','C_N^I')
                hold(app.UIAxes,'on')
                plot(app.UIAxesT,app.motion.S(1:min([length(app.motion.CNI),find(app.motion.t<t_end,1,'last')])),app.motion.CNI(1:min([length(app.motion.CNI),find(app.motion.t<t_end,1,'last')])),"Color",app.cmap(3,:),"LineWidth",1,'DisplayName','C_N^I')
                hold(app.UIAxesT,'on')
            end
            
            if app.PotentialCheckBox.Value
                plot(app.UIAxes,app.motion.alpha(1:length(app.motion.CNp)),app.motion.CNp,"Color",app.cmap(4,:),"LineWidth",1,'DisplayName','C_N^p')
                hold(app.UIAxes,'on')
                plot(app.UIAxesT,app.motion.S(1:min([length(app.motion.CNp),find(app.motion.t<t_end,1,'last')])),app.motion.CNp(1:min([length(app.motion.CNp),find(app.motion.t<t_end,1,'last')])),"Color",app.cmap(4,:),"LineWidth",1,'DisplayName','C_N^p')
                hold(app.UIAxesT,'on')
            end
            
            if app.LaggedCheckBox.Value
                plot(app.UIAxes,app.motion.alpha(1:length(app.motion.CNprime)),app.motion.CNprime,"Color",app.cmap(5,:),"LineWidth",1,'DisplayName','C_N''')
                hold(app.UIAxes,'on')
                plot(app.UIAxesT,app.motion.S(1:min([length(app.motion.CNprime),find(app.motion.t<t_end,1,'last')])),app.motion.CNprime(1:min([length(app.motion.CNprime),find(app.motion.t<t_end,1,'last')])),"Color",app.cmap(5,:),"LineWidth",1,'DisplayName','C_N''')
                hold(app.UIAxesT,'on')
            end
            
            if app.ModKirchoffCheckBox.Value
                plot(app.UIAxes,app.motion.alpha(1:length(app.motion.CNf)),app.motion.CNf,"Color",app.cmap(6,:),"LineWidth",1,'DisplayName','C_N^f')
                hold(app.UIAxes,'on')
                plot(app.UIAxesT,app.motion.S(1:min([length(app.motion.CNf),find(app.motion.t<t_end,1,'last')])),app.motion.CNf(1:min([length(app.motion.CNf),find(app.motion.t<t_end,1,'last')])),"Color",app.cmap(6,:),"LineWidth",1,'DisplayName','C_N^f')
                hold(app.UIAxesT,'on')
            end
            
            if app.AccVortexCheckBox.Value
                plot(app.UIAxes,app.motion.alpha(1:length(app.motion.CNv)),app.motion.CNv,"Color",app.cmap(7,:),"LineWidth",1,'DisplayName','C_N^v')
                hold(app.UIAxes,'on')
                plot(app.UIAxesT,app.motion.S(1:min([length(app.motion.CNv),find(app.motion.t<t_end,1,'last')])),app.motion.CNv(1:min([length(app.motion.CNv),find(app.motion.t<t_end,1,'last')])),"Color",app.cmap(7,:),"LineWidth",1,'DisplayName','C_N^v')
                hold(app.UIAxesT,'on')
            end
            
            if app.TotalCheckBox.Value
                plot(app.UIAxes,app.motion.alpha(1:length(app.motion.CN_LB)),app.motion.CN_LB,"Color",'r',"LineWidth",2,'DisplayName','total')
                hold(app.UIAxes,'on')
                plot(app.UIAxesT,app.motion.S(1:min([length(app.motion.CN_LB),find(app.motion.t<t_end,1,'last')])),app.motion.CN_LB(1:min([length(app.motion.CN_LB),find(app.motion.t<t_end,1,'last')])),"Color",'r',"LineWidth",2,'DisplayName','total')
                hold(app.UIAxesT,'on')
            end
                        
            axis(app.UIAxes,'auto')
            title(app.UIAxes,app.params)
            if length(app.UIAxes.Children)>1
                legend(app.UIAxes,'Location','NorthWest',"FontSize",20)
            else
                legend(app.UIAxes,'off')
            end
            
            
            axis(app.UIAxesT,"auto x")
            title(app.UIAxesT,app.params)
            if length(app.UIAxesT.Children)>1
                legend(app.UIAxesT,'Location','NorthWest',"FontSize",20)
            else
                legend(app.UIAxesT,'off')
            end
            
        end
        
        function plotf(app)
            % plot seppoint in alpha
            hold(app.UIAxes2,'off')
            plot(app.UIAxes2,app.airfoil.steady.alpha,app.airfoil.steady.fexp,"LineWidth",1,'DisplayName','f_{exp}')
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2,app.airfoil.steady.alpha,app.motion.f,"LineWidth",1,'DisplayName','f')
            plot(app.UIAxes2,app.motion.alpha(1:length(app.motion.fp)),app.motion.fp,"LineWidth",1,'DisplayName','f''')
            plot(app.UIAxes2,app.motion.alpha(1:length(app.motion.fpp)),app.motion.fpp,"LineWidth",1,'DisplayName','f''''')
            if length(app.UIAxes2.Children)>1
                legend(app.UIAxes2,'Location','SouthWest',"FontSize",20)
            else
                legend(app.UIAxes2,'off')
            end
            title(app.UIAxes2,app.params);
            
            % plot seppoint in time
            hold(app.UIAxes2T,'off')
            plot(app.UIAxes2T,app.motion.S(1:length(app.motion.alpha)),interp1(app.airfoil.steady.alpha,app.airfoil.steady.fexp,app.motion.alpha),"LineWidth",1,'DisplayName','f_{exp}')
            hold(app.UIAxes2T,'on')
            plot(app.UIAxes2T,app.airfoil.steady.alpha,app.motion.f,"LineWidth",1,'DisplayName','f')
            plot(app.UIAxes2T,app.motion.S(1:length(app.motion.fp)),app.motion.fp,"LineWidth",1,'DisplayName','f''')
            plot(app.UIAxes2T,app.motion.S(1:length(app.motion.fpp)),app.motion.fpp,"LineWidth",1,'DisplayName','f''''')
            if length(app.UIAxes2T.Children)>1
                legend(app.UIAxes2T,'Location','SouthWest',"FontSize",20)
            else
                legend(app.UIAxes2T,'off')
            end
            title(app.UIAxes2T,app.params);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('src'))
            if ~strcmp(pwd,'/Users/lucas/Documents/EPFL/PDM')
                cd('/Users/lucas/Documents/EPFL/PDM')
            end
            switch app.DatasetDropDown.Value
                case 'LB1989'
                    app.CaseDropDown.Enable = false;
                    % Set available models
                    app.ModelDropDown.Items = {'Beddoes-Leishman'};
                    % Set available models
                    app.airfoil = Airfoil('naca0012',0.1);
                    data = load('naca0012.mat');
                    data = data.naca0012;
                    
                    app.motion = PitchingMotion('alpha',data.alpha_xp,'CN',data.CN_xp,'M',0.3,'k',0.1);
                    app.motion.set('freq',app.motion.k*app.motion.V/(pi*app.airfoil.c));
                    app.motion.setName()
                    app.motion.setSinus(app.airfoil,deg2rad(data.mean),deg2rad(data.amp),2*pi/0.032,-pi/2);
                    
                    app.airfoil.steady = SteadyCurve(data.alpha_st,data.CN_st);
                case 'simcos2008'
                    run('data/2008_simcos/matlab/labbook.m')
                    app.CaseDropDown.Enable = false;
                    % Set available models
                    app.ModelDropDown.Items = {'Beddoes-Leishman'};
                    
                    % Static data
                    app.airfoil = Airfoil('OA209',0.3);
                    
                    load('static_corr','mA','mCl_corr')
                    app.airfoil.steady = SteadyCurve(mA,mCl_corr);
                    
                    % Dynamic data
                    nr=13;
                    
                    data = load(pressuredata(nr));
                    
                    % Drag missing, compute the pressure drag
                    CD = zeros(size(data.Cl));
                    for k=1:length(data.Cl)
                        CD(k) = sum(data.Cp(k,:)*data.xk);
                    end
                    
                    load('dynamic_corr','Alpha','Cl_corr')
                    % assuming CL = CN
                    app.motion = PitchingMotion('alpha',Alpha,'CN',Cl_corr.*cosd(Alpha),'k',LB(nr).k,'freq',LB(nr).fosc,'V',50);
                    app.motion.setSinus(app.airfoil,deg2rad(LB(nr).alpha_0),deg2rad(LB(nr).alpha_1),LB(nr).fosc,LB(nr).FS);
                    app.motion.setName('simcos')
                    app.motion.setCNsteady(app.airfoil.steady)
                    
                case 'SH2019'
                    run('/Users/lucas/src/codes_smarth/labbook.m')
                    % Set available ramps
                    app.CaseDropDown.Enable = true;
                    app.CaseDropDown.Items = {'18','14','22','67','68','69','70','26','27','28','29','84','85','86','87','30','89','34','71','38','93','42','75','46','97','50'};
                    % Set available models
                    app.ModelDropDown.Items = {'Beddoes-Leishman','BL-Sheng','BL-Sheng with expfit'};
                    
                    % Airfoil
                    app.airfoil = Airfoil('flatplate',0.15);
                    
                    % Static data
                    static = load('static_flatplate.mat');
                    app.airfoil.steady = SteadyCurve(static.alpha,static.CN,13.5);
                    
                    c = str2double(app.CaseDropDown.Value);
                    if LB(c).ms >= 13 && LB(c).ms < 100
                        load(loadmat(LB(c).ms,LB(c).mpt),'raw','inert');
                        inert.alpha = raw.alpha(raw.t>=0);                        
                        app.motion = RampUpMotion('alpha',inert.alpha,'t',inert.t,'V',LB(c).U,'alphadot',LB(c).alphadot);
                        Cl = inert.Cl;
                        Cd = inert.Cd;
                    else
                        load(loadmat(LB(c).ms,LB(c).mpt),'raw','zero');
                        app.motion = RampUpMotion('alpha',raw.alpha,'t',raw.t,'V',LB(c).U,'alphadot',LB(c).alphadot);
                        Cl = raw.Cl - zero.Cl; 
                        Cd = raw.Cd - zero.Cd;
                    end
                    msname = sprintf('ms%03i',LB(c).ms);
                    app.motion.setName(msname)                  
                    app.motion.setPitchRate(app.airfoil)
                    if strcmp(app.FilterSwitch.Value,'On')
                        fs = 1/app.motion.Ts;
                        Clfff = myFilter(Cl,fs);
                        Cdfff = myFilter(Cd,fs);
                        app.motion.setCL(Clfff);
                        app.motion.setCD(Cdfff);
                    elseif strcmp(app.FilterSwitch.Value,'Off')
                        app.motion.setCL(Cl);
                        app.motion.setCD(Cd);
                    else
                        error('The switch has an unexpected value.')
                    end
                    app.motion.computeAirfoilFrame();
                    app.motion.isolateRamp();
                otherwise
                    error('Dataset not defined.')
            end
            % set static slope and zero lift
            app.airfoil.steady.setAlpha0();
            app.airfoil.steady.fitKirchhoff();
            app.motion.findExpOnset();
            colormap(app.UIAxes,'parula')
            app.cmap = app.UIAxes.ColorOrder;
            app.TotalCheckBox.Value = 1;
            app.ExperimentalCheckBox.Value = 1;
            app.updatePlots()
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {469, 469};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {244, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: TpSlider
        function TpSliderValueChanged(app, event)
            app.Tp = app.TpSlider.Value;
            app.TpField.Value = app.Tp;
            app.updatePlots()
        end

        % Value changed function: TfSlider
        function TfSliderValueChanged(app, event)
            app.Tf = app.TfSlider.Value;
            app.TfField.Value = app.Tf;
            app.updatePlots()
        end

        % Value changed function: TvSlider
        function TvSliderValueChanged(app, event)
            app.Tv = app.TvSlider.Value;
            app.TvField.Value = app.Tv;
            app.updatePlots()
        end

        % Value changed function: TvlSlider
        function TvlSliderValueChanged(app, event)
            app.Tvl = app.TvlSlider.Value;
            app.TvlField.Value = app.Tvl;
            app.updatePlots()
        end

        % Value changed function: TpField
        function TpFieldValueChanged(app, event)
            app.Tp = app.TpField.Value;
            if app.Tp <= 20
                app.TpSlider.Value = app.Tp;
            end
            app.updatePlots()
        end

        % Value changed function: TfField
        function TfFieldValueChanged(app, event)
            app.Tf = app.TfField.Value;
            if app.Tf <= 10
                app.TfSlider.Value = app.Tf;
            end
            app.updatePlots()
        end

        % Value changed function: TvField
        function TvFieldValueChanged(app, event)
            app.Tv = app.TvField.Value;
            if app.Tv <= 10
                app.TvSlider.Value = app.Tv;
            end
            app.updatePlots()
        end

        % Value changed function: TvlField
        function TvlFieldValueChanged(app, event)
            app.Tvl = app.TvlField.Value;
            if app.Tvl <= 10
                app.TvlSlider.Value = app.Tvl;
            end
            app.updatePlots()
        end

        % Value changed function: CirculatoryCheckBox
        function CirculatoryCheckBoxValueChanged(app, event)
            app.updatePlots()
        end

        % Value changed function: ImpulsiveCheckBox
        function ImpulsiveCheckBoxValueChanged(app, event)
            app.updatePlots()
        end

        % Value changed function: PotentialCheckBox
        function PotentialCheckBoxValueChanged(app, event)
            app.updatePlots()
        end

        % Value changed function: LaggedCheckBox
        function LaggedCheckBoxValueChanged(app, event)
            app.updatePlots()
        end

        % Value changed function: ModKirchoffCheckBox
        function ModKirchoffCheckBoxValueChanged(app, event)
            app.updatePlots()
        end

        % Value changed function: AccVortexCheckBox
        function AccVortexCheckBoxValueChanged(app, event)
            app.updatePlots()
        end

        % Value changed function: TotalCheckBox
        function TotalCheckBoxValueChanged(app, event)
            app.updatePlots()
        end

        % Value changed function: ExperimentalCheckBox
        function ExperimentalCheckBoxValueChanged(app, event)
            app.updatePlots()
        end

        % Value changed function: FilterSwitch
        function FilterSwitchValueChanged(app, event)
            app.startupFcn()
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            app.startupFcn() % reload the correct dataset
        end

        % Value changed function: CaseDropDown
        function CaseDropDownValueChanged(app, event)
            app.startupFcn()
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
           if strcmp(app.ModelDropDown.Value,'Beddoes-Leishman')
               app.TpSlider.Enable = true; 
               app.TpField.Enable = true; 
           else
               app.TpSlider.Enable = false; 
               app.TpField.Enable = false;
           end
            app.updatePlots()
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            if ~isfolder('fig/BLapp')
                disp('New folder created under fig/BLapp/')
                mkdir('fig/BLapp')
            end
            exportgraphics(app.UIAxes,'fig/BLapp/CN_alpha.png')
        end

        % Button pushed function: SaveFigureButton_2
        function SaveFigureButton_2Pushed(app, event)
            if ~isfolder('fig/BLapp')
                disp('New folder created under fig/BLapp/')
                mkdir('fig/BLapp')
            end
            exportgraphics(app.UIAxes2,'fig/BLapp/f_curves.png')
        end

        % Button pushed function: SaveFigureButton_T
        function SaveFigureButton_TPushed(app, event)
            if ~isfolder('fig/BLapp')
                disp('New folder created under fig/BLapp/')
                mkdir('fig/BLapp')
            end
            exportgraphics(app.UIAxesT,'fig/BLapp/CN_convectime.png')
        end

        % Button pushed function: SaveFigureButton_2T
        function SaveFigureButton_2TPushed(app, event)
            if ~isfolder('fig/BLapp')
                disp('New folder created under fig/BLapp/')
                mkdir('fig/BLapp')
            end
            exportgraphics(app.UIAxes2T,'fig/BLapp/f_convectime.png')
        end

        % Value changed function: StaticCheckBox
        function StaticCheckBoxValueChanged(app, event)
            app.updatePlots()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TpSliderLabel = uilabel(app.LeftPanel);
            app.TpSliderLabel.Text = 'Tp';
            app.TpSlider = uislider(app.LeftPanel);
            app.TpSlider.Limits = [0 20];
            app.TpSlider.ValueChangedFcn = createCallbackFcn(app, @TpSliderValueChanged, true);
            app.TpSlider.Value = 1.7;
            app.TfSliderLabel = uilabel(app.LeftPanel);
            app.TfSliderLabel.Text = 'Tf';
            app.TfSlider = uislider(app.LeftPanel);
            app.TfSlider.Limits = [0 10];
            app.TfSlider.ValueChangedFcn = createCallbackFcn(app, @TfSliderValueChanged, true);
            app.TfSlider.Value = 3;
            app.TvSliderLabel = uilabel(app.LeftPanel);
            app.TvSliderLabel.Text = 'Tv';
            app.TvSlider = uislider(app.LeftPanel);
            app.TvSlider.Limits = [0 10];
            app.TvSlider.ValueChangedFcn = createCallbackFcn(app, @TvSliderValueChanged, true);
            app.TvSlider.Value = 6;
            app.CirculatoryCheckBox = uicheckbox(app.LeftPanel);
            app.CirculatoryCheckBox.ValueChangedFcn = createCallbackFcn(app, @CirculatoryCheckBoxValueChanged, true);
            app.CirculatoryCheckBox.Text = 'Circulatory';
            app.ImpulsiveCheckBox = uicheckbox(app.LeftPanel);
            app.ImpulsiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImpulsiveCheckBoxValueChanged, true);
            app.ImpulsiveCheckBox.Text = 'Impulsive';
            app.PotentialCheckBox = uicheckbox(app.LeftPanel);
            app.PotentialCheckBox.ValueChangedFcn = createCallbackFcn(app, @PotentialCheckBoxValueChanged, true);
            app.PotentialCheckBox.Text = 'Potential';
            app.AccVortexCheckBox = uicheckbox(app.LeftPanel);
            app.AccVortexCheckBox.ValueChangedFcn = createCallbackFcn(app, @AccVortexCheckBoxValueChanged, true);
            app.AccVortexCheckBox.Text = 'Acc. Vortex';
            app.TotalCheckBox = uicheckbox(app.LeftPanel);
            app.TotalCheckBox.ValueChangedFcn = createCallbackFcn(app, @TotalCheckBoxValueChanged, true);
            app.TotalCheckBox.Text = 'Total';
            app.ExperimentalCheckBox = uicheckbox(app.LeftPanel);
            app.ExperimentalCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExperimentalCheckBoxValueChanged, true);
            app.ExperimentalCheckBox.Text = 'Experimental';
            app.ModKirchoffCheckBox = uicheckbox(app.LeftPanel);
            app.ModKirchoffCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModKirchoffCheckBoxValueChanged, true);
            app.ModKirchoffCheckBox.Text = 'Mod. Kirchoff';
            app.LaggedCheckBox = uicheckbox(app.LeftPanel);
            app.LaggedCheckBox.ValueChangedFcn = createCallbackFcn(app, @LaggedCheckBoxValueChanged, true);
            app.LaggedCheckBox.Text = 'Lagged';
            app.DatasetDropDownLabel = uilabel(app.LeftPanel);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.LeftPanel);
            app.DatasetDropDown.Items = {'LB1989', 'simcos2008', 'SH2019'};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Value = 'SH2019';
            app.ModelDropDownLabel = uilabel(app.LeftPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.LeftPanel);
            app.ModelDropDown.Items = {'Beddoes-Leishman', 'BL-Sheng', 'BL-Sheng with expfit'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'Beddoes-Leishman';
            app.FilterexpdataSwitchLabel = uilabel(app.LeftPanel);
            app.FilterexpdataSwitchLabel.Text = 'Filter exp. data';
            app.FilterSwitch = uiswitch(app.LeftPanel, 'slider');
            app.FilterSwitch.ValueChangedFcn = createCallbackFcn(app, @FilterSwitchValueChanged, true);
            app.TfField = uieditfield(app.LeftPanel, 'numeric');
            app.TfField.Limits = [0 Inf];
            app.TfField.ValueChangedFcn = createCallbackFcn(app, @TfFieldValueChanged, true);
            app.TfField.Value = 3;
            app.TvField = uieditfield(app.LeftPanel, 'numeric');
            app.TvField.Limits = [0 Inf];
            app.TvField.ValueChangedFcn = createCallbackFcn(app, @TvFieldValueChanged, true);
            app.TvField.Value = 6;
            app.TvlSliderLabel = uilabel(app.LeftPanel);
            app.TvlSliderLabel.Text = 'Tvl';
            app.TvlSlider = uislider(app.LeftPanel);
            app.TvlSlider.Limits = [0 10];
            app.TvlSlider.ValueChangedFcn = createCallbackFcn(app, @TvlSliderValueChanged, true);
            app.TvlSlider.Value = 6;
            app.TpField = uieditfield(app.LeftPanel, 'numeric');
            app.TpField.Limits = [0 Inf];
            app.TpField.ValueChangedFcn = createCallbackFcn(app, @TpFieldValueChanged, true);
            app.TpField.Value = 1.7;
            app.TvlField = uieditfield(app.LeftPanel, 'numeric');
            app.TvlField.Limits = [0 Inf];
            app.TvlField.ValueChangedFcn = createCallbackFcn(app, @TvlFieldValueChanged, true);
            app.TvlField.Value = 6;
            app.cDropDownLabel = uilabel(app.LeftPanel);
            app.cDropDownLabel.Text = 'c';
            app.CaseDropDown = uidropdown(app.LeftPanel);
            app.CaseDropDown.Items = {'22', '26', '30', '34'};
            app.CaseDropDown.ValueChangedFcn = createCallbackFcn(app, @CaseDropDownValueChanged, true);
            app.CaseDropDown.Value = '22';
            app.StaticCheckBox = uicheckbox(app.LeftPanel);
            app.StaticCheckBox.ValueChangedFcn = createCallbackFcn(app, @StaticCheckBoxValueChanged, true);
            app.StaticCheckBox.Text = 'Static';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.loadsinangleTab = uitab(app.TabGroup);
            app.loadsinangleTab.Title = 'loads in angle';
            app.UIAxes = uiaxes(app.loadsinangleTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '\alpha (°)')
            ylabel(app.UIAxes, 'C_N')
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [-0.5 2.2];
            app.SaveFigureButton = uibutton(app.loadsinangleTab, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.loadsintimeTab = uitab(app.TabGroup);
            app.loadsintimeTab.Title = 'loads in time';
            app.UIAxesT = uiaxes(app.loadsintimeTab);
            title(app.UIAxesT, 'Normal coefficients')
            xlabel(app.UIAxesT, 't_c')
            ylabel(app.UIAxesT, 'C_N')
            app.UIAxesT.XLim = [0 20];
            app.UIAxesT.YLim = [-0.5 2.2];
            app.SaveFigureButton_T = uibutton(app.loadsintimeTab, 'push');
            app.SaveFigureButton_T.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButton_TPushed, true);
            app.SaveFigureButton_T.Text = 'Save Figure';
            app.seppointinalphaTab = uitab(app.TabGroup);
            app.seppointinalphaTab.Title = 'seppoint in alpha';
            app.UIAxes2 = uiaxes(app.seppointinalphaTab);
            title(app.UIAxes2, 'Separation point')
            xlabel(app.UIAxes2, '\alpha (°)')
            ylabel(app.UIAxes2, 'f')
            app.SaveFigureButton_2 = uibutton(app.seppointinalphaTab, 'push');
            app.SaveFigureButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButton_2Pushed, true);
            app.SaveFigureButton_2.Text = 'Save Figure';
            app.seppointintimeTab = uitab(app.TabGroup);
            app.seppointintimeTab.Title = 'seppoint in time';
            app.UIAxes2T = uiaxes(app.seppointintimeTab);
            title(app.UIAxes2T, 'Separation point')
            xlabel(app.UIAxes2T, 't_c')
            ylabel(app.UIAxes2T, 'f')
            app.SaveFigureButton_2T = uibutton(app.seppointintimeTab, 'push');
            app.SaveFigureButton_2T.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButton_2TPushed, true);
            app.SaveFigureButton_2T.Text = 'Save Figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BLapp2020

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end